classdef response_circular < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button_38  matlab.ui.control.Button
        Button_37  matlab.ui.control.Button
        Button_36  matlab.ui.control.Button
        Button_35  matlab.ui.control.Button
        Button_34  matlab.ui.control.Button
        Button_33  matlab.ui.control.Button
        Button_32  matlab.ui.control.Button
        Button_31  matlab.ui.control.Button
        Button_30  matlab.ui.control.Button
        Button_29  matlab.ui.control.Button
        Button_28  matlab.ui.control.Button
        Button_27  matlab.ui.control.Button
        Button_26  matlab.ui.control.Button
        Button_25  matlab.ui.control.Button
        Button_24  matlab.ui.control.Button
        Image2     matlab.ui.control.Image
        Image      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_24
        function ButtonPushed(app, event)
            assignin("base","sub_ans",1);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_25
        function Button_6Pushed(app, event)
            assignin("base","sub_ans",2);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_26
        function Button_11Pushed(app, event)
            assignin("base","sub_ans",3);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_27
        function Button_16Pushed(app, event)
            assignin("base","sub_ans",4);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_28
        function Button_21Pushed(app, event)
            assignin("base","sub_ans",5);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_29
        function Button_2Pushed(app, event)
            assignin("base","sub_ans",6);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_30
        function Button_7Pushed(app, event)
            assignin("base","sub_ans",7);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_31
        function Button_12Pushed(app, event)
            assignin("base","sub_ans",8);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_32
        function Button_17Pushed(app, event)
            assignin("base","sub_ans",9);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_33
        function Button_22Pushed(app, event)
            assignin("base","sub_ans",10);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_34
        function Button_3Pushed(app, event)
            assignin("base","sub_ans",11);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_35
        function Button_8Pushed(app, event)
            assignin("base","sub_ans",12);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_36
        function Button_13Pushed(app, event)
            assignin("base","sub_ans",13);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_37
        function Button_18Pushed(app, event)
            assignin("base","sub_ans",14);
            uiresume(app.UIFigure)
        end

        % Button pushed function: Button_38
        function Button_23Pushed(app, event)
            assignin("base","sub_ans",15);
            uiresume(app.UIFigure)
        end

        % Callback function
        function Button_4Pushed(app, event)
            assignin("base","sub_ans",33);
            uiresume(app.UIFigure)
        end

        % Callback function
        function Button_9Pushed(app, event)
            assignin("base","sub_ans",34);
            uiresume(app.UIFigure)
        end

        % Callback function
        function Button_14Pushed(app, event)
            assignin("base","sub_ans",35);
            uiresume(app.UIFigure)
        end

        % Callback function
        function Button_19Pushed(app, event)
            assignin("base","sub_ans",36);
            uiresume(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'azimu.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Happy_Emoji.png';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_24.Text = '1';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_25.Text = '2';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_26.Text = '3';
            app.Button_27 = uibutton(app.UIFigure, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_27.Text = '4';
            app.Button_28 = uibutton(app.UIFigure, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_28.Text = '5';
            app.Button_29 = uibutton(app.UIFigure, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_29.Text = '6';
            app.Button_30 = uibutton(app.UIFigure, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_30.Text = '7';
            app.Button_31 = uibutton(app.UIFigure, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_31.Text = '8';
            app.Button_32 = uibutton(app.UIFigure, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_32.Text = '9';
            app.Button_33 = uibutton(app.UIFigure, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_33.Text = '10';
            app.Button_34 = uibutton(app.UIFigure, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_34.Text = '11';
            app.Button_35 = uibutton(app.UIFigure, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_35.Text = '12';
            app.Button_36 = uibutton(app.UIFigure, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_36.Text = '13';
            app.Button_37 = uibutton(app.UIFigure, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_37.Text = '14';
            app.Button_38 = uibutton(app.UIFigure, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_38.Text = '15';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = response_circular

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end